classdef Position < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        File                           matlab.ui.container.Menu
        Calibrate                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CameraParametersPanel          matlab.ui.container.Panel
        AcquisitionFrameRateEditField  matlab.ui.control.NumericEditField
        AcquisitionFrameRateEditFieldLabel  matlab.ui.control.Label
        EnableAcquisitionFrameRateCheckBox  matlab.ui.control.CheckBox
        GainAutoDropDown               matlab.ui.control.DropDown
        GainAutoDropDownLabel          matlab.ui.control.Label
        GaindBEditField                matlab.ui.control.NumericEditField
        GaindBEditFieldLabel           matlab.ui.control.Label
        ExposureAutoDropDown           matlab.ui.control.DropDown
        ExposureAutoDropDownLabel      matlab.ui.control.Label
        PixelFormatDropDownLabel       matlab.ui.control.Label
        ExposureTimeEditField          matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel     matlab.ui.control.Label
        PixelFormatDropDown            matlab.ui.control.DropDown
        CamPramDropDown                matlab.ui.control.DropDown
        SelectLabel                    matlab.ui.control.Label
        MonitorSwitch                  matlab.ui.control.Switch
        MonitorDropDown                matlab.ui.control.DropDown
        MonitorLabel                   matlab.ui.control.Label
        MeasureSwitch                  matlab.ui.control.Switch
        MeasureDropDown                matlab.ui.control.DropDown
        MeasureDropDownLabel           matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        ROIToolsPanel                  matlab.ui.container.Panel
        MinROISizeEditField            matlab.ui.control.NumericEditField
        MinROISizeEditFieldLabel       matlab.ui.control.Label
        AdsorbButton                   matlab.ui.control.Button
        xywhEditField                  matlab.ui.control.EditField
        xywhEditFieldLabel             matlab.ui.control.Label
        ySpinner                       matlab.ui.control.Spinner
        xSpinner                       matlab.ui.control.Spinner
        xySpinnerLabel                 matlab.ui.control.Label
        LaserROIUnlockButton           matlab.ui.control.StateButton
        CrosshairUnlockButton          matlab.ui.control.StateButton
        LaserROIOffButton              matlab.ui.control.StateButton
        CrosshairOffButton             matlab.ui.control.StateButton
        TabGroup                       matlab.ui.container.TabGroup
        MeasuringCameraTab             matlab.ui.container.Tab
        MeasureAxes                    matlab.ui.control.UIAxes
        MonitoringCameraTab            matlab.ui.container.Tab
        MonitorAxes                    matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        MeasuringOpticParametersPanel  matlab.ui.container.Panel
        AngleLaserSpinner              matlab.ui.control.Spinner
        AngleLensSpinner               matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        OpticalDropDown                matlab.ui.control.DropDown
        CameraLabel_2                  matlab.ui.control.Label
        PMAGEditField                  matlab.ui.control.NumericEditField
        PMAGLabel                      matlab.ui.control.Label
        PixelSizeEditField             matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel       matlab.ui.control.Label
        ResultsPanel                   matlab.ui.container.Panel
        LaserDiameterEditField         matlab.ui.control.NumericEditField
        LaserAreapxLabel_2             matlab.ui.control.Label
        ResultDropDown                 matlab.ui.control.DropDown
        CameraLabel                    matlab.ui.control.Label
        LaserAreaEditField             matlab.ui.control.NumericEditField
        LaserAreapxLabel               matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        DistancemEditFieldLabel        matlab.ui.control.Label
        PixelDistanceEditField         matlab.ui.control.NumericEditField
        PixelDistanceEditFieldLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        DeviceTable table                   % Device table
        MeasureVid                          % video input object
        MonitorVid                          % video input object
        ROIposLaserMeasure (1,4) double     % initial ROI position
        ROIposCrosshairMeasure (1,2) double
        ROIposLaserPointMeasure (1,2) double 
        hROILaserMeasure
        hROICrosshairMeasure
        hROILaserPointMeasure
        ROIposLaserMonitor (1,4) double
        ROIposCrosshairMonitor (1,2) double
        ROIposLaserPointMonitor (1,2) double 
        hROILaserMonitor
        hROICrosshairMonitor
        hROILaserPointMonitor

         Pix2PhisicCoeffiCalibrated

    end

    properties (Dependent)
        Pix2PhisicCoeffiTheory
    end

    methods (Access = private)
        function refreshCameraList(app)
            % 更新设备列表
            updateDeviceTable(app);
            deviceList = strcat(app.DeviceTable(:,:).Name, '-', app.DeviceTable(:,:).Adaptor);
            app.MeasureDropDown.Items = deviceList;
            app.MonitorDropDown.Items = deviceList;
            % 更新参数列表
            app.CamPramDropDown.Items = deviceList;
            app.CamPramDropDownValueChanged(app);
        end

        function updateDeviceTable(app)
            % Create the device table with all recognized devices
            % Get a list of all the installed adaptors
            adaptors = imaqhwinfo().InstalledAdaptors;

            % Iterate over every element in an array
            for adaptor = adaptors % Step through each adaptor
                devices = imaqhwinfo(cell2mat(adaptor)).DeviceInfo; % get all devices for adaptor
                for ii = 1:size(devices,2)
                    % Add the device to the device table
                    supportForm  = string(devices(ii).SupportedFormats);
                    supportForm(contains(supportForm,"p")) = []; % 'Mono10p','Mono12p' 会报错
                    selectedForm = supportForm(1);
                    app.DeviceTable = [app.DeviceTable;
                        {string(adaptor), ii, string(devices(ii).DeviceName), supportForm, selectedForm}];
                end
            end

            % Set the table column names for easy access in the future
            % 若无任何设备被添加(空表), 先构造带正确列名的空表, 避免列数不匹配报错
            if isempty(app.DeviceTable)
                app.DeviceTable = table('Size',[0 5],'VariableTypes', ...
                    ["string","double","string","string","string"], ...
                    'VariableNames', ["Adaptor","Number","Name","SupportedFormats","SelectedFormat"]);
            else
                app.DeviceTable.Properties.VariableNames = ...
                    ["Adaptor","Number","Name","SupportedFormats","SelectedFormat"];
            end
        end

        function refreshPreview(app,vid,hAx,tag)
            % 1.开启实时预览硬件加速
            vid.FramesPerTrigger = 1;
            vid.TriggerRepeat = Inf;
            triggerconfig(vid, 'immediate');
            vid.PreviewFullBitDepth = "on";
            vid.ReturnedColorSpace = "grayscale";
            vid.IgnoreDroppedFrames = 'on'; % Force disable errors when
            % dropped frames occur and to continue acquisition.
            src = getselectedsource(vid);
            % src.PacketSize = 16000; % Bytes.
            % To lower the CPU load, raise the value of the PacketSize source property while
            % making sure it is not larger than the Ethernet card jumbo frame/packet size.
            % https://www.mathworks.com/matlabcentral/answers/91834-how-do-i-calculate-the-packet-delay-for-a-gige-vision-camera-to-prevent-dropped-frames?s_tid=srchtitle
            % Starting in MATLAB R2013a the toolbox does an automatic
            % configuration of the PacketSize value. This should lead to
            % increased performance and lower CPU load. Due to this
            % optimization of PacketSize, you may need to adjust your PacketDelay accordingly.
            bandwidthGbps = 5; % Gig
            delay = CalculatePacketDelay(vid, src.AcquisitionFrameRate, bandwidthGbps);
            src.PacketDelay = delay; % 如果仍会丢包，可适当增加
            % For optimum performance and to prevent dropped frames
            % configure the camera to insert an appropriate delay between
            % packets by setting the PacketDelay source property.

            % 2.Create the image object in which you want to
            % display the video preview data.
            vidRes = vid.VideoResolution;
            imWidth = vidRes(1);
            imHeight = vidRes(2);
            nBands = vid.NumberOfBands;
            hImage = image(hAx,zeros(imHeight, imWidth, nBands));
            axis(hAx, 'image','off','tight');
            hold(hAx, 'on');
            hImage.Tag = tag;
            % 3.Set up the update preview window function.
            setappdata(hImage,'UpdatePreviewWindowFcn', ...
                @(obj,event,hImage) previewCallback(app,obj,event,hImage))
            % 3.preview on
            preview(vid,hImage);
        end

        function previewCallback(app,obj,event,hImage)

            persistent frameCount;

            % 2.check which image is previewed
            tag = hImage.Tag;
            switch tag
                case 'Measure'
                    hCrosshair = app.hROICrosshairMeasure;
                    roiposCrosshair = app.ROIposCrosshairMeasure;
                    hroiLaser = app.hROILaserMeasure;
                    hroiLaserPoint = app.hROILaserPointMeasure;
                case 'Monitor'
                    hCrosshair = app.hROICrosshairMonitor;
                    roiposCrosshair = app.ROIposCrosshairMonitor;
                    hroiLaser = app.hROILaserMonitor;
                    hroiLaserPoint = app.hROILaserPointMonitor;
                otherwise
                    return
            end

            % 4.获取当前帧数据
            frame = event.Data;

            % update preview image
            set(hImage, 'CData', frame);

            % --- 5.降低耗时算法的频率 ---
            % 每5帧处理一次
            if isempty(frameCount), frameCount = 0; end
            frameCount = frameCount + 1;

            if mod(frameCount, 3) == 0
                if strcmp(app.ResultDropDown.Value,tag) % 结果选择与图像显示模式一致
                    if ~isempty(hroiLaser)&&isvalid(hroiLaser)
                        % 取 ROI 子图（用原始位深数据）
                        roipos = round(hroiLaser.Position); % [x y w h]
                        [H, W] = size(frame);
                        x1 = max(1, roipos(1)); y1 = max(1, roipos(2));
                        x2 = min(W, x1 + roipos(3) - 1);
                        y2 = min(H, y1 + roipos(4) - 1);
                        if x2<=x1 || y2<=y1
                            uialert(app.UIFigure,"ROI非法","Error")
                            drawnow limitrate;
                            return;
                        end
                        roiImage = double(frame(y1:y2, x1:x2));

                        % track laser
                        [wCent,area,eDia,xy] = detectLaserSpotRobust(roiImage, app.MinROISizeEditField.Value);

                        if ~isempty(wCent)
                            wCentRaw = wCent + [x1, y1];
                            xyRaw =  xy + [x1, y1, x1, y1]; % x,y limit of roi
                            % 边界绝对安全限制 (Clamping)
                            x1 = max(1, xyRaw(1));
                            y1 = max(1,xyRaw(2));
                            x2 = min(W, xyRaw(3));
                            y2 = min(H, xyRaw(4));
                            roipos = [x1, y1, x2 - x1, y2 - y1];
                            % update laser ROI
                            hroiLaser.Position = roipos;
                            hroiLaserPoint.Position = wCentRaw;

                            if ~isempty(hCrosshair)&&isvalid(hCrosshair)
                                % calculate crosshair-laser pixel distance
                                v = wCentRaw - roiposCrosshair;
                                crossLaserDistance = (v(1)>=0)*(vecnorm(v,2)) + (v(1)<0)*(-vecnorm(v,2));

                                switch tag
                                    case 'Measure'
                                        app.ROIposLaserMeasure = roipos;
                                        app.ROIposLaserPointMeasure = wCentRaw;
                                    case 'Monitor'
                                        app.ROIposLaserMonitor = roipos;
                                        app.ROIposLaserPointMonitor = wCentRaw;
                                end

                                % calculate object distance deviation
                                if isempty(app.Pix2PhisicCoeffiCalibrated)
                                    c = app.Pix2PhisicCoeffiTheory;
                                    deltaDistance = c*crossLaserDistance; % μm
                                    if abs(deltaDistance)<=100
                                        app.DistanceEditField.FontColor = [0,1,0];
                                    else
                                        app.DistanceEditField.FontColor = [1,0,0];
                                    end
                                else
                                    % use calibrated coefficient

                                end

                                % update Result uicontrols
                                app.PixelDistanceEditField.Value = crossLaserDistance;
                                app.LaserAreaEditField.Value= area;
                                app.LaserDiameterEditField.Value = eDia;
                                app.DistanceEditField.Value = deltaDistance;
                            end
                        else
                            % update Result uicontrols
                            app.PixelDistanceEditField.Value = [];
                            app.LaserAreaEditField.Value= 0;
                            app.LaserDiameterEditField.Value = 0;
                            app.DistanceEditField.Value = [];
                        end
                    end
                end
            end
            drawnow limitrate;
        end

        function updateLaserROIPosition(app, pos, mode)
            % pos 是一个 [x, y, width, height] 的向量
            switch mode
                case 'Measure'
                    app.ROIposLaserMeasure = pos;
                    app.hROILaserPointMeasure.Position = [pos(1)+pos(3)/2-1,pos(2)+pos(4)/2-1];
                case 'Monitor'
                    app.ROIposLaserMonitor = pos;
                    app.hROILaserPointMonitor.Position = [pos(1)+pos(3)/2-1,pos(2)+pos(4)/2-1];
            end
            % update uicontrol
            app.xywhEditField.Value = sprintf('[%.1f,%.1f,%.1f,%.1f]',pos);
        end

        function updateCrosshairROIPosition(app, pos, mode)
            % pos 是一个 [x, y] 的向量
            switch mode
                case 'Measure'
                    app.ROIposCrosshairMeasure = pos;
                case 'Monitor'
                    app.ROIposCrosshairMonitor = pos;
            end
            % update xSpinner ySpinner
            app.xSpinner.Value = pos(1);
            app.ySpinner.Value = pos(2);
        end

    end

    methods
        function c = get.Pix2PhisicCoeffiTheory(app)
            ps = app.PixelSizeEditField.Value;
            PMAG = app.PMAGEditField.Value;
            alpha = app.AngleLensSpinner.Value;
            beta = app.AngleLaserSpinner.Value;
            c =ps/(PMAG*cosd(alpha)*(tand(alpha)-tand(beta)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. 查询设备并更新list
            refreshCameraList(app)
            % 2. 从 JSON 加载上次保存的 ROI/crosshair 坐标
            try
                jsonPath = fullfile(pwd, 'roi_settings.json');
                if isfile(jsonPath)
                    data = jsondecode(fileread(jsonPath));
                    fns = fieldnames(data);
                    for k = 1:numel(fns)
                        if isprop(app, fns{k})
                            app.(fns{k}) = reshape(data.(fns{k}), 1, []); % 强制行向量
                        end
                    end
                end
            catch ME
                warning('加载ROI坐标失败: %s', ME.message);
            end
        end

        % Value changed function: MeasureSwitch
        function MeasureSwitchValueChanged(app, event)
            value = app.MeasureSwitch.Value;
            deviceID = app.MeasureDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                errordlg("No valid devices.")
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % switch to MeasureTab
            app.TabGroup.SelectedTab = app.MeasuringCameraTab;
            switch value
                case 'On'
                    % preview on
                    if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)
                        stop(app.MeasureVid)
                        delete(app.MeasureVid)
                    end
                    try
                        vid = videoinput(string(selectedCamera.Adaptor), ...
                            selectedCamera.Number, selectedCamera.SelectedFormat);
                        app.MeasureVid = vid;
                    catch ME
                        uialert(app.UIFigure,ME.message,"Error")
                        app.MeasureSwitch.Value = 'Off';
                        return
                    end
                    % customized preview
                    hAx = app.MeasureAxes;
                    hAx.NextPlot = "replaceall";
                    tag = 'Measure';
                    app.refreshPreview(vid,hAx,tag)

                    % update real time Frame rate
                    src = getselectedsource(vid);
                    app.AcquisitionFrameRateEditField.Value = src.BslResultingFrameBurstRate; % readback real time frame time

                    % execute ROI call backs
                    CrosshairOffButtonValueChanged(app,event) % on/off cross hair
                    LaserROIOffButtonValueChanged(app,event)  % on/off Laser ROI
                    app.CamPramDropDown.ValueIndex = deviceID;
                    CamPramDropDownValueChanged(app, event)   % Update camera parameter uicontrols
                case 'Off'
                    % preview off and cleanup
                    if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)
                        try stoppreview(app.MeasureVid); catch, end
                        try delete(app.MeasureVid); catch, end
                    end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % cleanup camera devices
            try
                if isvalid(app.MeasureVid)
                    try stoppreview(app.MeasureVid); catch, end
                    try delete(app.MeasureVid); catch, end
                end
            catch
            end
            try
                if isvalid(app.MonitorVid)
                    try stoppreview(app.MonitorVid); catch, end
                    try delete(app.MonitorVid); catch, end
                end
            catch
            end

            % 询问是否保存当前 ROI/crosshair 坐标到 JSON
            uconf = uiconfirm(app.UIFigure, '是否保存当前 ROI 与十字光标坐标？', ...
                '保存坐标', 'Options', {'保存','不保存','取消'}, 'DefaultOption', 1,'CancelOption',3);
            if uconf == 3
                return  % 取消: 不关闭 App
            end
            switch uconf
                case '保存'
                    try
                        data = struct();
                        coords = {'ROIposLaserMeasure','ROIposCrosshairMeasure','ROIposLaserPointMeasure', ...
                            'ROIposLaserMonitor','ROIposCrosshairMonitor','ROIposLaserPointMonitor'};
                        for k = 1:numel(coords)
                            if isprop(app, coords{k}) && ~isempty(app.(coords{k}))
                                data.(coords{k}) = app.(coords{k});
                            end
                        end
                        jsonPath = fullfile(pwd, 'roi_settings.json');
                        writestruct(data,jsonPath,PreserveInfAndNaN=false)
                        fprintf("ROI parameters saved at%s\n",jsonPath)
                    catch ME
                        uialert(app.UIFigure, sprintf('保存坐标失败: %s', ME.message), '错误');
                    end
                otherwise
            end

            delete(app)

        end

        % Value changed function: MonitorSwitch
        function MonitorSwitchValueChanged(app, event)
            value = app.MonitorSwitch.Value;
            deviceID = app.MonitorDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                errordlg("No valid devices.")
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % switch to MonitorTab
            app.TabGroup.SelectedTab = app.MonitoringCameraTab;
            switch value
                case 'On'
                    % preview on
                    if ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)
                        stop(app.MonitorVid)
                        delete(app.MonitorVid)
                        try
                            vid = videoinput(string(selectedCamera.Adaptor), ...
                                selectedCamera.Number, selectedCamera.SelectedFormat);
                            app.MonitorVid = vid;
                        catch ME
                            uialert(app.UIFigure,ME.message,"Error")
                            app.MonitorSwitch.Value = 'Off';
                            return
                        end
                    else
                        try
                            vid = videoinput(string(selectedCamera.Adaptor), ...
                                selectedCamera.Number, selectedCamera.SelectedFormat);
                            app.MonitorVid = vid;
                        catch ME
                            uialert(app.UIFigure,ME.message,"Error")
                            app.MonitorSwitch.Value = 'Off';
                            return
                        end
                    end
                    % customized preview
                    hAx = app.MonitorAxes;
                    hAx.NextPlot = "replace";
                    % hAx.MaxRenderedResolution = 1920;
                    tag = 'Monitor';
                    app.refreshPreview(vid,hAx,tag)

                    % update real time Frame rate
                    src = getselectedsource(vid);
                    app.AcquisitionFrameRateEditField.Value = src.BslResultingFrameBurstRate; % readback real time frame time

                    % execute ROI call backs
                    CrosshairOffButtonValueChanged(app,event) % on/off cross hair
                    LaserROIOffButtonValueChanged(app,event)  % on/off Laser ROI
                    app.CamPramDropDown.ValueIndex = deviceID;
                    CamPramDropDownValueChanged(app, event)   % Update camera parameter uicontrols
                case 'Off'
                    % preview off and cleanup
                    if ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)
                        if isvalid(app.MonitorVid)
                            try stoppreview(app.MonitorVid); catch, end
                            try delete(app.MonitorVid); catch, end
                        end
                    end
            end

        end

        % Value changed function: CamPramDropDown
        function CamPramDropDownValueChanged(app, event)
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                errordlg("No valid devices.")
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % get parameters and update uicontrols
            app.PixelFormatDropDown.Items = selectedCamera.SupportedFormats;
            app.PixelFormatDropDown.Value = vid.VideoFormat;
            src = getselectedsource(vid);
            app.ExposureTimeEditField.Value = src.ExposureTime;
            app.ExposureAutoDropDown.Value = src.ExposureAuto;
            app.GaindBEditField.Value = src.Gain;
            app.GainAutoDropDown.Value = src.GainAuto;
            switch src.AcquisitionFrameRateEnable
                case "True"
                    app.EnableAcquisitionFrameRateCheckBox.Value = true;
                    app.AcquisitionFrameRateEditField.Editable = "on";
                case "False"
                    app.EnableAcquisitionFrameRateCheckBox.Value = false;
                    app.AcquisitionFrameRateEditField.Editable = "off";
            end
            if ~inuse
                app.AcquisitionFrameRateEditField.Value = src.AcquisitionFrameRate;
            else
                app.AcquisitionFrameRateEditField.Value = src.BslResultingFrameBurstRate; % readback real time frame time
            end
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end

        end

        % Value changed function: PixelFormatDropDown
        function PixelFormatDropDownValueChanged(app, event)
            pixelform = string(app.PixelFormatDropDown.Value);
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            % update device table
            app.DeviceTable.SelectedFormat(deviceID)= pixelform;
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                % recreate the vid with the specific pixel format
                try stoppreview(app.MeasureVid); catch, end
                try delete(app.MeasureVid); catch, end
                MeasureSwitchValueChanged(app, event)
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                % recreate the vid with the specific pixel format
                try stoppreview(app.MonitorVid); catch, end
                try delete(app.MonitorVid); catch, end
                MonitorSwitchValueChanged(app, event)
            end
        end

        % Value changed function: ExposureTimeEditField
        function ExposureTimeEditFieldValueChanged(app, event)
            value = app.ExposureTimeEditField.Value;
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % set parameters and update uicontrols
            src = getselectedsource(vid);
            src.ExposureTime = value;
            app.AcquisitionFrameRateEditField.Value = src.AcquisitionFrameRate;
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end
        end

        % Value changed function: ExposureAutoDropDown
        function ExposureAutoDropDownValueChanged(app, event)
            value = app.ExposureAutoDropDown.Value;
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % set parameters and update uicontrols
            src = getselectedsource(vid);
            src.ExposureAuto = value;
            pause(0.2)
            app.ExposureTimeEditField.Value = src.ExposureTime;
            app.AcquisitionFrameRateEditField.Value = src.AcquisitionFrameRate;
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end
        end

        % Value changed function: GaindBEditField
        function GaindBEditFieldValueChanged(app, event)
            value = app.GaindBEditField.Value;
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % set parameters and update uicontrols
            src = getselectedsource(vid);
            src.Gain = value;
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end
        end

        % Value changed function: GainAutoDropDown
        function GainAutoDropDownValueChanged(app, event)
            value = app.GainAutoDropDown.Value;
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % set parameters and update uicontrols
            src = getselectedsource(vid);
            src.GainAuto = value;
            pause(0.2)
            app.GaindBEditField.Value = src.Gain;
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end
        end

        % Value changed function: EnableAcquisitionFrameRateCheckBox
        function EnableAcquisitionFrameRateCheckBoxValueChanged(app, event)
            value = app.EnableAcquisitionFrameRateCheckBox.Value;
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % set parameters and update uicontrols
            src = getselectedsource(vid);
            if value
                src.AcquisitionFrameRateEnable = "True";
                app.AcquisitionFrameRateEditField.Value = src.AcquisitionFrameRate;
                app.ExposureTimeEditField.Value = src.ExposureTime;
                app.AcquisitionFrameRateEditField.Editable = "on";
            else
                src.AcquisitionFrameRateEnable = "False";
                app.AcquisitionFrameRateEditField.Editable = "off";
            end
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end
        end

        % Value changed function: AcquisitionFrameRateEditField
        function AcquisitionFrameRateEditFieldValueChanged(app, event)
            value = app.AcquisitionFrameRateEditField.Value;
            % get vidobj to be set
            deviceID = app.CamPramDropDown.ValueIndex;
            if isempty(app.DeviceTable)
                return
            end
            selectedCamera = app.DeviceTable(deviceID,:);
            % check is camera in use
            try
                measureDeviceID = app.MeasureVid.DeviceID;
            catch
                measureDeviceID = 0;
            end
            try
                monitorDeviceID = app.MonitorVid.DeviceID;
            catch
                monitorDeviceID = 0;
            end
            if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)&&isequal(deviceID,measureDeviceID)
                inuse = true;
                vid = app.MeasureVid;
            elseif ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)&&isequal(deviceID,monitorDeviceID)
                inuse = true;
                vid = app.MonitorVid;
            else
                % camera is not in use
                inuse = false;
                vid = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, selectedCamera.SelectedFormat);
            end
            % set parameters and update uicontrols
            src = getselectedsource(vid);
            src.AcquisitionFrameRate = value;
            pause(0.2)
            app.ExposureTimeEditField.Value = src.ExposureTime;
            app.AcquisitionFrameRateEditField.Value = src.BslResultingFrameBurstRate; % readback real time frame time
            % release vid
            if ~inuse
                try delete(vid); clear vid; catch, end
            end
        end

        % Value changed function: CrosshairOffButton
        function CrosshairOffButtonValueChanged(app, event)
            value = app.CrosshairOffButton.Value;
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    roi = app.hROICrosshairMeasure;
                    if all(app.ROIposCrosshairMeasure)
                        roipos = app.ROIposCrosshairMeasure;
                    else

                        if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)
                            % initiolize
                            vidRes = app.MeasureVid.VideoResolution;
                            imWidth = vidRes(1);
                            imHeight = vidRes(2);
                            roipos = [imWidth,imHeight]./2;
                        else
                            return
                        end
                    end
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    roi = app.hROICrosshairMonitor;
                    if all(app.ROIposCrosshairMonitor)
                        roipos = app.ROIposCrosshairMonitor;
                    else
                        if ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)
                            % initiolize
                            vidRes = app.MonitorVid.VideoResolution;
                            imWidth = vidRes(1);
                            imHeight = vidRes(2);
                            roipos = [imWidth,imHeight]./2;
                        else
                            return
                        end
                    end
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end
            if value
                % crosshair on
                if ~isempty(roi)&&isvalid(roi)
                    roi.Visible = "on";
                else
                    % creat crosshair roi
                    roi = images.roi.Crosshair(hAx,'Position',roipos, ...
                        'Color',[0.0660,0.4430,0.7450],'LineWidth',1, ...
                        'Label','X-ray','LabelAlpha',0.3);
                    roi.Deletable = false;
                    roi.InteractionsAllowed = "none";
                    % update uicontrols
                    app.xSpinner.Value = roipos(1);
                    app.ySpinner.Value = roipos(2);

                    % store roi handle
                    switch tagTitle
                        case 'Measuring Camera'
                            app.hROICrosshairMeasure = roi;
                            % add listener：只要 ROI 位置发生变化（拖动或缩放），就执行回调
                            addlistener(app.hROICrosshairMeasure, 'ROIMoved', ...
                                @(src, event) app.updateCrosshairROIPosition(src.Position,'Measure'));
                            app.ROIposCrosshairMeasure = roipos;
                        case 'Monitoring Camera'
                            app.hROICrosshairMonitor = roi;
                            % add listener：只要 ROI 位置发生变化（拖动或缩放），就执行回调
                            addlistener(app.hROICrosshairMonitor, 'ROIMoved', ...
                                @(src, event) app.updateCrosshairROIPosition(src.Position,'Monitor'));
                            app.ROIposCrosshairMonitor = roipos;
                        otherwise
                    end
                end
                % switch button texture
                app.CrosshairOffButton.Text = 'Crosshair On';
            else
                % crosshair off
                if ~isempty(roi)&&isvalid(roi)
                    roi.Visible = "off";
                end
                % switch button texture
                app.CrosshairOffButton.Text = 'Crosshair Off';
            end
        end

        % Value changed function: LaserROIOffButton
        function LaserROIOffButtonValueChanged(app, event)
            value = app.LaserROIOffButton.Value;
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    roi = app.hROILaserMeasure;
                    roiPoint = app.hROILaserPointMeasure;
                    if all(app.ROIposLaserMeasure)
                        roipos = app.ROIposLaserMeasure;
                        roiPointPos = app.ROIposLaserPointMeasure;
                    else
                        if ~isempty(app.MeasureVid)&&isvalid(app.MeasureVid)
                            % initiolize
                            vidRes = app.MeasureVid.VideoResolution;
                            imWidth = vidRes(1);
                            imHeight = vidRes(2);
                            width = imWidth;
                            height = imHeight;
                            roipos = [imWidth/2-width/2,imHeight/2-height/2,width,height];
                            roiPointPos = [roipos(1) + roipos(3)/2-1, roipos(2) + roipos(4)/2-1];
                        else
                            return
                        end
                    end
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    roi = app.hROILaserMonitor;
                    roiPoint = app.hROILaserPointMonitor;
                    if all(app.ROIposLaserMonitor)
                        roipos = app.ROIposLaserMonitor;
                        roiPointPos = app.ROIposLaserPointMonitor;
                    else
                        if ~isempty(app.MonitorVid)&&isvalid(app.MonitorVid)
                            % initiolize
                            vidRes = app.MonitorVid.VideoResolution;
                            imWidth = vidRes(1);
                            imHeight = vidRes(2);
                            width = imWidth;
                            height = imHeight;
                            roipos = [imWidth/2-width/2,imHeight/2-height/2,width,height];
                            roiPointPos = [roipos(1) + roipos(3)/2-1, roipos(2) + roipos(4)/2-1];
                        end
                    end
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end
            if value
                % LaserROI on
                if ~isempty(roi)&&isvalid(roi)
                    roi.Visible = "on";
                    roiPoint.Visible = "on";
                else
                    % creat Laser Rectangle roi
                    roi = images.roi.Rectangle(hAx,'Position',roipos, ...
                        'Color',[0.8660,0.3290,0],'LineWidth',1,'FaceAlpha',0, ...
                        'Label','Laser','LabelAlpha',0.3);
                    roi.Deletable = false;
                    roi.InteractionsAllowed = "none";
                    roiPoint = images.roi.Point(hAx,'Position',roiPointPos, ...
                        'Color',[0.8660,0.3290,0],'LineWidth',1,'MarkerSize',4);
                    roiPoint.Deletable = false;
                    roiPoint.InteractionsAllowed = "none";
                    % update uicontrols
                    app.xywhEditField.Value = sprintf('[%.1f,%.1f,%d,%.1f]',roipos);
                    % store roi handle
                    switch tagTitle
                        case 'Measuring Camera'
                            app.hROILaserMeasure = roi;
                            app.hROILaserPointMeasure = roiPoint;
                            % add listener：只要 ROI 位置发生变化（拖动或缩放），就执行回调
                            addlistener(app.hROILaserMeasure, 'ROIMoved', ...
                                @(src, event) app.updateLaserROIPosition(src.Position,'Measure'));
                            app.ROIposLaserMeasure  = roipos;
                        case 'Monitoring Camera'
                            app.hROILaserMonitor = roi; 
                            app.hROILaserPointMonitor = roiPoint;
                            % add listener：只要 ROI 位置发生变化（拖动或缩放），就执行回调
                            addlistener(app.hROILaserMonitor, 'ROIMoved', ...
                                @(src, event) app.updateLaserROIPosition(src.Position,'Monitor'));
                            app.ROIposLaserMonitor = roipos;
                        otherwise
                    end
                end
                % switch button texture
                app.LaserROIOffButton.Text = 'LaserROI On';
            else
                % LaserROI off
                if ~isempty(roi)&&isvalid(roi)
                    roi.Visible = "off";
                    roiPoint.Visible = "off";
                end
                % switch button texture
                app.LaserROIOffButton.Text = 'LaserROI Off';
            end
        end

        % Value changed function: CrosshairUnlockButton
        function CrosshairUnlockButtonValueChanged(app, event)
            value = app.CrosshairUnlockButton.Value;
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    roi = app.hROICrosshairMeasure;
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    roi = app.hROICrosshairMonitor;
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end

            imgObj = findobj(hAx, 'Type', 'image');
            if isempty(imgObj)
                return
            end

            if value
                % unLocked
                if ~isempty(roi)&&isvalid(roi)
                    roi.InteractionsAllowed = "All";
                else
                    return
                end
                % switch button texture
                app.CrosshairUnlockButton.Text = 'UnLocked';
                % switch xSpinner
                app.xSpinner.Enable = "on";
                app.ySpinner.Enable = "on";
            else
                % Locked
                if ~isempty(roi)&&isvalid(roi)
                    roi.InteractionsAllowed = "none";
                else
                    return
                end
                % switch button texture
                app.CrosshairUnlockButton.Text = 'Locked';
                % switch xSpinner
                app.xSpinner.Enable = "off";
                app.ySpinner.Enable = "off";
            end
        end

        % Value changed function: LaserROIUnlockButton
        function LaserROIUnlockButtonValueChanged(app, event)
            value = app.LaserROIUnlockButton.Value;
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    roi = app.hROILaserMeasure;
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    roi = app.hROILaserMonitor;
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end

            imgObj = findobj(hAx, 'Type', 'image');
            if isempty(imgObj)
                return
            end

            if value
                % unLocked
                if ~isempty(roi)&&isvalid(roi)
                    roi.InteractionsAllowed = "All";
                else
                    return
                end
                % switch button texture
                app.LaserROIUnlockButton.Text = 'UnLocked';
            else
                % Locked
                if ~isempty(roi)&&isvalid(roi)
                    roi.InteractionsAllowed = "none";
                else
                    return
                end
                % switch button texture
                app.LaserROIUnlockButton.Text = 'Locked';
            end
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged(app, event)
            value = app.xSpinner.Value;
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    roi = app.hROICrosshairMeasure;
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    roi = app.hROICrosshairMonitor;
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end

            imgObj = findobj(hAx, 'Type', 'image');
            if isempty(imgObj)
                return
            end

            pos = roi.Position;
            pos(1) = value;
            roi.Position = pos;

        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(app, event)
            value = app.ySpinner.Value;
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    roi = app.hROICrosshairMeasure;
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    roi = app.hROICrosshairMonitor;
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end

            imgObj = findobj(hAx, 'Type', 'image');
            if isempty(imgObj)
                return
            end

            pos = roi.Position;
            pos(2) = value;
            roi.Position = pos;
        end

        % Button pushed function: AdsorbButton
        function AdsorbButtonPushed(app, event)
            % check which Tab is on
            tagTitle = app.TabGroup.SelectedTab.Title;
            switch tagTitle
                case 'Measuring Camera'
                    hROICross = app.hROICrosshairMeasure;
                    hROILaserPoint = app.hROILaserPointMeasure;
                    hAx = app.MeasureAxes;
                case 'Monitoring Camera'
                    hROICross = app.hROICrosshairMonitor;
                    hROILaserPoint = app.hROILaserPointMonitor;
                    hAx = app.MonitorAxes;
                otherwise
                    return
            end

            imgObj = findobj(hAx, 'Type', 'image');
            if isempty(imgObj)
                return
            end

            % adsorb the cross hair to the laser center point
            pos = hROILaserPoint.Position;
            hROICross.Position = pos;
            % save to property
            switch tagTitle
                case 'Measuring Camera'
                    app.ROIposCrosshairMeasure = pos;
                case 'Monitoring Camera'
                    app.ROIposCrosshairMonitor = pos;
                otherwise
                    return
            end
            % update uicontrol
            app.xSpinner.Value = pos(1);
            app.ySpinner.Value = pos(2);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.Calibrate = uimenu(app.UIFigure);
            app.Calibrate.Text = 'Calibrate';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MeasureDropDownLabel = uilabel(app.LeftPanel);
            app.MeasureDropDownLabel.Text = 'Measure';
            app.MeasureDropDown = uidropdown(app.LeftPanel);
            app.MeasureDropDown.Items = {'...'};
            app.MeasureDropDown.Value = '...';
            app.MeasureSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MeasureSwitch.ValueChangedFcn = createCallbackFcn(app, @MeasureSwitchValueChanged, true);
            app.MonitorLabel = uilabel(app.LeftPanel);
            app.MonitorLabel.Text = 'Monitor ';
            app.MonitorDropDown = uidropdown(app.LeftPanel);
            app.MonitorDropDown.Items = {'...'};
            app.MonitorDropDown.Value = '...';
            app.MonitorSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MonitorSwitch.ValueChangedFcn = createCallbackFcn(app, @MonitorSwitchValueChanged, true);
            app.CameraParametersPanel = uipanel(app.LeftPanel);
            app.CameraParametersPanel.Title = 'Camera Parameters';
            app.SelectLabel = uilabel(app.CameraParametersPanel);
            app.SelectLabel.Text = 'Camera';
            app.CamPramDropDown = uidropdown(app.CameraParametersPanel);
            app.CamPramDropDown.Items = {'...'};
            app.CamPramDropDown.ValueChangedFcn = createCallbackFcn(app, @CamPramDropDownValueChanged, true);
            app.CamPramDropDown.Value = '...';
            app.PixelFormatDropDown = uidropdown(app.CameraParametersPanel);
            app.PixelFormatDropDown.Items = {'...'};
            app.PixelFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @PixelFormatDropDownValueChanged, true);
            app.PixelFormatDropDown.Value = '...';
            app.ExposureTimeEditFieldLabel = uilabel(app.CameraParametersPanel);
            app.ExposureTimeEditFieldLabel.Tooltip = {'ExposureTime'; 'Exposure time in microseconds.'};
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.ExposureTimeEditField.Limits = [20 1000000];
            app.ExposureTimeEditField.ValueDisplayFormat = '%.3f';
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeEditFieldValueChanged, true);
            app.ExposureTimeEditField.Value = 20;
            app.PixelFormatDropDownLabel = uilabel(app.CameraParametersPanel);
            app.PixelFormatDropDownLabel.Text = 'Pixel Format';
            app.ExposureAutoDropDownLabel = uilabel(app.CameraParametersPanel);
            app.ExposureAutoDropDownLabel.Text = 'Exposure Auto';
            app.ExposureAutoDropDown = uidropdown(app.CameraParametersPanel);
            app.ExposureAutoDropDown.Items = {'Off', 'Once', 'Continuous'};
            app.ExposureAutoDropDown.ValueChangedFcn = createCallbackFcn(app, @ExposureAutoDropDownValueChanged, true);
            app.ExposureAutoDropDown.Value = 'Off';
            app.GaindBEditFieldLabel = uilabel(app.CameraParametersPanel);
            app.GaindBEditFieldLabel.Text = 'Gain (dB)';
            app.GaindBEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.GaindBEditField.Limits = [0 48];
            app.GaindBEditField.ValueChangedFcn = createCallbackFcn(app, @GaindBEditFieldValueChanged, true);
            app.GainAutoDropDownLabel = uilabel(app.CameraParametersPanel);
            app.GainAutoDropDownLabel.Text = 'Gain Auto';
            app.GainAutoDropDown = uidropdown(app.CameraParametersPanel);
            app.GainAutoDropDown.Items = {'Off', 'Once', 'Continuous'};
            app.GainAutoDropDown.ValueChangedFcn = createCallbackFcn(app, @GainAutoDropDownValueChanged, true);
            app.GainAutoDropDown.Value = 'Off';
            app.EnableAcquisitionFrameRateCheckBox = uicheckbox(app.CameraParametersPanel);
            app.EnableAcquisitionFrameRateCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableAcquisitionFrameRateCheckBoxValueChanged, true);
            app.EnableAcquisitionFrameRateCheckBox.Text = 'Enable Acquisition Frame Rate';
            app.EnableAcquisitionFrameRateCheckBox.Value = true;
            app.AcquisitionFrameRateEditFieldLabel = uilabel(app.CameraParametersPanel);
            app.AcquisitionFrameRateEditFieldLabel.Text = 'Acquisition Frame Rate';
            app.AcquisitionFrameRateEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.AcquisitionFrameRateEditField.Limits = [0.1 1000000];
            app.AcquisitionFrameRateEditField.ValueDisplayFormat = '%.3f';
            app.AcquisitionFrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitionFrameRateEditFieldValueChanged, true);
            app.AcquisitionFrameRateEditField.Value = 30;
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.MeasuringCameraTab = uitab(app.TabGroup);
            app.MeasuringCameraTab.Title = 'Measuring Camera';
            app.MeasureAxes = uiaxes(app.MeasuringCameraTab);
            title(app.MeasureAxes, 'Title')
            xlabel(app.MeasureAxes, 'X')
            ylabel(app.MeasureAxes, 'Y')
            zlabel(app.MeasureAxes, 'Z')
            app.MonitoringCameraTab = uitab(app.TabGroup);
            app.MonitoringCameraTab.Title = 'Monitoring Camera';
            app.MonitorAxes = uiaxes(app.MonitoringCameraTab);
            title(app.MonitorAxes, 'Title')
            xlabel(app.MonitorAxes, 'X')
            ylabel(app.MonitorAxes, 'Y')
            zlabel(app.MonitorAxes, 'Z')
            app.ROIToolsPanel = uipanel(app.CenterPanel);
            app.ROIToolsPanel.Title = 'ROI Tools';
            app.CrosshairOffButton = uibutton(app.ROIToolsPanel, 'state');
            app.CrosshairOffButton.ValueChangedFcn = createCallbackFcn(app, @CrosshairOffButtonValueChanged, true);
            app.CrosshairOffButton.Text = 'Crosshair Off';
            app.LaserROIOffButton = uibutton(app.ROIToolsPanel, 'state');
            app.LaserROIOffButton.ValueChangedFcn = createCallbackFcn(app, @LaserROIOffButtonValueChanged, true);
            app.LaserROIOffButton.Text = 'LaserROI Off';
            app.CrosshairUnlockButton = uibutton(app.ROIToolsPanel, 'state');
            app.CrosshairUnlockButton.ValueChangedFcn = createCallbackFcn(app, @CrosshairUnlockButtonValueChanged, true);
            app.CrosshairUnlockButton.Text = 'Locked';
            app.LaserROIUnlockButton = uibutton(app.ROIToolsPanel, 'state');
            app.LaserROIUnlockButton.ValueChangedFcn = createCallbackFcn(app, @LaserROIUnlockButtonValueChanged, true);
            app.LaserROIUnlockButton.Text = 'Locked';
            app.xySpinnerLabel = uilabel(app.ROIToolsPanel);
            app.xySpinnerLabel.Enable = 'off';
            app.xySpinnerLabel.Text = 'x,y';
            app.xSpinner = uispinner(app.ROIToolsPanel);
            app.xSpinner.Limits = [1 Inf];
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @xSpinnerValueChanged, true);
            app.xSpinner.Editable = 'off';
            app.xSpinner.Enable = 'off';
            app.xSpinner.Value = 1;
            app.ySpinner = uispinner(app.ROIToolsPanel);
            app.ySpinner.Limits = [1 Inf];
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ySpinnerValueChanged, true);
            app.ySpinner.Editable = 'off';
            app.ySpinner.Enable = 'off';
            app.ySpinner.Value = 1;
            app.xywhEditFieldLabel = uilabel(app.ROIToolsPanel);
            app.xywhEditFieldLabel.Text = 'x,y,w,h';
            app.xywhEditField = uieditfield(app.ROIToolsPanel, 'text');
            app.xywhEditField.Editable = 'off';
            app.xywhEditField.Value = '[1,1,200,200]';
            app.AdsorbButton = uibutton(app.ROIToolsPanel, 'push');
            app.AdsorbButton.ButtonPushedFcn = createCallbackFcn(app, @AdsorbButtonPushed, true);
            app.AdsorbButton.Text = 'Adsorb';
            app.MinROISizeEditFieldLabel = uilabel(app.ROIToolsPanel);
            app.MinROISizeEditFieldLabel.Text = 'Min ROI Size (px) ';
            app.MinROISizeEditField = uieditfield(app.ROIToolsPanel, 'numeric');
            app.MinROISizeEditField.Limits = [0 Inf];
            app.MinROISizeEditField.ValueDisplayFormat = '%d';
            app.MinROISizeEditField.Tooltip = {'动态ROI边长下限(像素), 防止追小特征时窗口缩小; 0=不限 '};
            app.MinROISizeEditField.Value = 60;
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultsPanel = uipanel(app.RightPanel);
            app.ResultsPanel.Title = 'Results';
            app.PixelDistanceEditFieldLabel = uilabel(app.ResultsPanel);
            app.PixelDistanceEditFieldLabel.Text = 'Pixel Distance';
            app.PixelDistanceEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.PixelDistanceEditField.AllowEmpty = 'on';
            app.DistancemEditFieldLabel = uilabel(app.ResultsPanel);
            app.DistancemEditFieldLabel.Text = 'Distance (μm)';
            app.DistanceEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.DistanceEditField.ValueDisplayFormat = '%.2f';
            app.DistanceEditField.AllowEmpty = 'on';
            app.LaserAreapxLabel = uilabel(app.ResultsPanel);
            app.LaserAreapxLabel.Text = 'Laser Area (px)';
            app.LaserAreaEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.LaserAreaEditField.ValueDisplayFormat = '%.1f';
            app.CameraLabel = uilabel(app.ResultsPanel);
            app.CameraLabel.Text = 'System';
            app.ResultDropDown = uidropdown(app.ResultsPanel);
            app.ResultDropDown.Items = {'Measure', 'Monitor'};
            app.ResultDropDown.Value = 'Measure';
            app.LaserAreapxLabel_2 = uilabel(app.ResultsPanel);
            app.LaserAreapxLabel_2.Text = 'Laser Diameter (px)';
            app.LaserDiameterEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.LaserDiameterEditField.ValueDisplayFormat = '%.1f';
            app.MeasuringOpticParametersPanel = uipanel(app.RightPanel);
            app.MeasuringOpticParametersPanel.Title = 'Measuring Optic Parameters';
            app.PixelSizemEditFieldLabel = uilabel(app.MeasuringOpticParametersPanel);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size (μm)';
            app.PixelSizeEditField = uieditfield(app.MeasuringOpticParametersPanel, 'numeric');
            app.PixelSizeEditField.Limits = [0 Inf];
            app.PixelSizeEditField.Tooltip = {'2.74 for Measure'; '3.45 for Monitor'};
            app.PixelSizeEditField.Value = 2.74;
            app.PMAGLabel = uilabel(app.MeasuringOpticParametersPanel);
            app.PMAGLabel.Text = 'PMAG          ×';
            app.PMAGEditField = uieditfield(app.MeasuringOpticParametersPanel, 'numeric');
            app.PMAGEditField.Limits = [0 Inf];
            app.PMAGEditField.Tooltip = {'EH1:0.7'; 'EH2:0.5'};
            app.PMAGEditField.Value = 0.5;
            app.CameraLabel_2 = uilabel(app.MeasuringOpticParametersPanel);
            app.CameraLabel_2.Text = 'System';
            app.OpticalDropDown = uidropdown(app.MeasuringOpticParametersPanel);
            app.OpticalDropDown.Items = {'Measure', 'Monitor'};
            app.OpticalDropDown.Value = 'Measure';
            app.Label = uilabel(app.MeasuringOpticParametersPanel);
            app.Label.Text = 'α,β (°)';
            app.AngleLensSpinner = uispinner(app.MeasuringOpticParametersPanel);
            app.AngleLensSpinner.Editable = 'off';
            app.AngleLensSpinner.Tooltip = {'Lens-X-ray angle'};
            app.AngleLensSpinner.Value = -10;
            app.AngleLaserSpinner = uispinner(app.MeasuringOpticParametersPanel);
            app.AngleLaserSpinner.Editable = 'off';
            app.AngleLaserSpinner.Tooltip = {'Laser-X-rya angle'};
            app.AngleLaserSpinner.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Position

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end